classdef SYNTH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        Slider                  matlab.ui.control.Slider
        SliderLabel             matlab.ui.control.Label
        VolumeKnob              matlab.ui.control.Knob
        VolumeKnobLabel         matlab.ui.control.Label
        CenterPanel             matlab.ui.container.Panel
        MediaControlLabel       matlab.ui.control.Label
        PlayButton              matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        TimeAxes                matlab.ui.control.UIAxes
        FreqAxes                matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
        ImportFileButton        matlab.ui.control.Button
        FileNameEditField       matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % One .wav file object that will be used through whole program
    properties (Access = private)
        WaveFileObj
    end
    
    % All helper methods to handle gui interaction
    methods (Access = private)
        
        %{
            Initializes the .wav file object to be used

            @param filePath - the path of the file read in
        %}
        function loadWav(app, filePath)
        
            app.WaveFileObj = WaveFile(filePath);
        end
        
        %{
            Plays sound of .wav file, from the beginning
        %}
        function playSound(app)
         
            app.WaveFileObj.play();    
        end

        %{
            Stops playing sound (if currently playing)
        %}
        function stopSound(app)

            app.WaveFileObj.stop();
        end

        %{
            Plots the two graphs of our data.
        %}
        function loadPlot(app)
            
            timeAxesHandle = app.TimeAxes;
            freqAxesHandle = app.FreqAxes;

            plot(timeAxesHandle, app.WaveFileObj.timeData);
            plot(freqAxesHandle, abs(app.WaveFileObj.freqData));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            % read in data. ref:
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            % https://www.mathworks.com/help/matlab/ref/fullfile.html
            
            [filename, path] = uigetfile();
            app.FileNameEditField.Value = filename;

            loadWav(app, fullfile(path, filename));

            % load the plots whenever new file is uploaded
            loadPlot(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            playSound(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            stopSound(app);
        end

        % Button down function: TimeAxes
        function TimeAxesButtonDown(app, event)

        end

        % Button down function: FreqAxes
        function FreqAxesButtonDown(app, event)
            
        end

        % Value changed function: VolumeKnob
        function VolumeKnobValueChanged(app, event)
            
            value = app.VolumeKnob.Value;

            loadPlot(app);
        end

        % Value changing function: VolumeKnob
        function VolumeKnobValueChanging(app, event)
            changingValue = event.Value;
            app.WaveFileObj = app.WaveFileObj.changeAmplitude(1*value/50);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;

            app.WaveFileObj = app.WaveFileObj.changeAmplitude(1*value/50);
            loadPlot(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VolumeKnobLabel = uilabel(app.LeftPanel);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.LeftPanel, 'continuous');
            app.VolumeKnob.MajorTicks = [];
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeKnobValueChanged, true);
            app.VolumeKnob.ValueChangingFcn = createCallbackFcn(app, @VolumeKnobValueChanging, true);
            app.VolumeKnob.Value = 50;
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 50;
            app.CenterPanel = uipanel(app.GridLayout);
            app.FreqAxes = uiaxes(app.CenterPanel);
            title(app.FreqAxes, 'Frequency Waveform')
            xlabel(app.FreqAxes, 'Angular Frequency')
            ylabel(app.FreqAxes, 'Amplitude')
            zlabel(app.FreqAxes, 'Z')
            app.FreqAxes.XTick = [];
            app.FreqAxes.XTickLabel = '';
            app.FreqAxes.YTick = [];
            app.FreqAxes.YTickLabel = '';
            app.FreqAxes.ZTick = [];
            app.FreqAxes.ButtonDownFcn = createCallbackFcn(app, @FreqAxesButtonDown, true);
            app.TimeAxes = uiaxes(app.CenterPanel);
            title(app.TimeAxes, 'Audio Waveform')
            xlabel(app.TimeAxes, 'Time')
            ylabel(app.TimeAxes, 'Amplitude')
            zlabel(app.TimeAxes, 'Z')
            app.TimeAxes.XTick = [];
            app.TimeAxes.XTickLabel = '';
            app.TimeAxes.YTick = [];
            app.TimeAxes.YTickLabel = '';
            app.TimeAxes.ZTick = [];
            app.TimeAxes.ButtonDownFcn = createCallbackFcn(app, @TimeAxesButtonDown, true);
            app.StopButton = uibutton(app.CenterPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton = uibutton(app.CenterPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.MediaControlLabel = uilabel(app.CenterPanel);
            app.MediaControlLabel.Text = 'Media Control';
            app.RightPanel = uipanel(app.GridLayout);
            app.FileNameEditFieldLabel = uilabel(app.RightPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.RightPanel, 'text');
            app.ImportFileButton = uibutton(app.RightPanel, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SYNTH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end